classdef appli_simple_RS232 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        InterfacedecommunicationRS232Label  matlab.ui.control.Label
        COMPortEditFieldLabel  matlab.ui.control.Label
        COMPortEditField       matlab.ui.control.NumericEditField
        ConnectButton          matlab.ui.control.Button
        SwitchOnButton         matlab.ui.control.Button
        SwitchOffButton        matlab.ui.control.Button
        PortListLabel          matlab.ui.control.Label
        HelpButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        s % serial port
        portList % list of the different available comm ports
    end
    
    methods (Access = private)
        
        function results = rs232Interrupt(app, src, ~)
            rLine = readline(src);
            rLineChar = char(rLine);
            app.PortListLabel.Text = rLineChar;
            drawnow;
            flush(app.s)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.portList = serialportlist();
            app.PortListLabel.Text = app.portList
            app.SwitchOffButton.Enable = false;
            app.SwitchOnButton.Enable = false;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            try
                app.s = serialport("COM"+app.COMPortEditField.Value, 115200);
                configureTerminator(app.s, "CR/LF");
                % configureCallback(app.s, "byte", 1, @app.rs232Interrupt)
                configureCallback(app.s, "terminator", @app.rs232Interrupt);
                app.SwitchOffButton.Enable = true;
                app.SwitchOnButton.Enable = true;
            catch
                warning('Problem using serial.');
            end
            drawnow;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            open appli_simple_RS232.mlx;
        end

        % Button pushed function: SwitchOnButton
        function SwitchOnButtonPushed(app, event)
            writeline(app.s, 'a');
        end

        % Button pushed function: SwitchOffButton
        function SwitchOffButtonPushed(app, event)
            writeline(app.s, 'e');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InterfacedecommunicationRS232Label = uilabel(app.GridLayout);
            app.InterfacedecommunicationRS232Label.Text = 'Interface de communication RS232';
            app.COMPortEditFieldLabel = uilabel(app.GridLayout);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConnectButton = uibutton(app.GridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SwitchOnButton = uibutton(app.GridLayout, 'push');
            app.SwitchOnButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchOnButtonPushed, true);
            app.SwitchOnButton.Text = 'Switch On';
            app.SwitchOffButton = uibutton(app.GridLayout, 'push');
            app.SwitchOffButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchOffButtonPushed, true);
            app.SwitchOffButton.Text = 'Switch Off';
            app.PortListLabel = uilabel(app.GridLayout);
            app.PortListLabel.Text = 'Port List';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appli_simple_RS232

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end